classdef RC_Circuit_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AboutButton                 matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SavePlotButton              matlab.ui.control.Button
        FinalVoltageLabel           matlab.ui.control.Label
        VoltageTauLabel             matlab.ui.control.Label
        TauLabel                    matlab.ui.control.Label
        SimulateButton              matlab.ui.control.Button
        ExportButton                matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        VoltageEditField            matlab.ui.control.NumericEditField
        VoltageFVEditFieldLabel     matlab.ui.control.Label
        CapacitanceEditField        matlab.ui.control.NumericEditField
        CapacitanceFEditFieldLabel  matlab.ui.control.Label
        ResistanceEditField         matlab.ui.control.NumericEditField
        ResistanceLabel             matlab.ui.control.Label
        CircuitAxes                 matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        Time
        ChargeVoltage
        DischargeVoltage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


                app.ResistanceEditField.Value = 1000;
                app.CapacitanceEditField.Value = 0.001;
                app.VoltageEditField.Value = 5;

                app.TauLabel.Text = "Time Constant:";

                title(app.UIAxes,"RC Circuit Simulation")

                xlabel(app.UIAxes,"Time (seconds)")

                ylabel(app.UIAxes,"Voltage (Volts)")

                grid(app.UIAxes,"on")
                
                drawCircuit(app);

    
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
% Read Inputs
R = app.ResistanceEditField.Value;
C = app.CapacitanceEditField.Value;
V0 = app.VoltageEditField.Value;

% Validate
if R <= 0 || C <= 0 || V0 <= 0
    uialert(app.UIFigure,...
        'Values must be greater than zero.',...
        'Invalid Input');
    return
end

% Calculations
tau = R*C;

t = 0:0.01:(5*tau);

Vc_charge = V0*(1-exp(-t/(R*C)));

Vc_discharge = V0*exp(-t/(R*C));

Voltage_tau = V0*(1-exp(-1));

FinalVoltage = Vc_charge(end);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% THIS IS WHERE THE PLOTTING SECTION GOES
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

cla(app.UIAxes)

plot(app.UIAxes,t,Vc_charge,...
    'LineWidth',2)

hold(app.UIAxes,'on')

plot(app.UIAxes,t,Vc_discharge,...
    '--',...
    'LineWidth',2)

plot(app.UIAxes,...
    tau,...
    Voltage_tau,...
    'o',...
    'MarkerSize',10,...
    'MarkerFaceColor','g')

xline(app.UIAxes,...
    tau,...
    '--',...
    '\tau')

yline(app.UIAxes,...
    Voltage_tau,...
    '--',...
    '63.2%')

grid(app.UIAxes,'on')

xlabel(app.UIAxes,'Time (seconds)')
ylabel(app.UIAxes,'Voltage (V)')
title(app.UIAxes,'RC Circuit Transient Response')

legend(app.UIAxes,...
    'Charging',...
    'Discharging',...
    'Time Constant',...
    'Location','best')

hold(app.UIAxes,'off')

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% UPDATE LABELS
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

app.TauLabel.Text = sprintf('Time Constant (τ): %.3f s',tau);

app.VoltageTauLabel.Text = sprintf('Voltage @ τ: %.2f V',Voltage_tau);

app.FinalVoltageLabel.Text = sprintf('Final Voltage: %.2f V',FinalVoltage);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
if isempty(app.Time)
    uialert(app.UIFigure,...
        'Run the simulation first.',...
        'No Data');
    return;
end

Data = table(...
    app.Time',...
    app.ChargeVoltage',...
    app.DischargeVoltage',...
    'VariableNames',...
    {'Time','ChargeVoltage','DischargeVoltage'});

[file,path] = uiputfile('RC_Data.csv',...
    'Save Data As');

if isequal(file,0)
    return;
end

writetable(Data,fullfile(path,file));

uialert(app.UIFigure,...
    'CSV exported successfully!',...
    'Export Complete');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            

                cla(app.UIAxes)

                app.ResistanceEditField.Value = 1000;

                app.CapacitanceEditField.Value = 0.001;

                app.VoltageEditField.Value = 5;

                app.TauLabel.Text = "Time Constant:";
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
         [file,path] = uiputfile('RC_Circuit_Plot.png',...
                    'Save Plot As');

                if isequal(file,0)
                    return;
                end

                exportgraphics(app.UIAxes,...
                    fullfile(path,file),...
                    'Resolution',300);

                uialert(app.UIFigure,...
                    'Plot saved successfully!',...
                    'Save Complete');
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msg = sprintf([ ...
                    'RC Circuit Transient Response Simulator\n\n' ...
                    'Version 1.0\n\n' ...
                    'Developed in MATLAB App Designer\n\n' ...
                    'This application simulates capacitor charging\n' ...
                    'and discharging in an RC circuit.\n\n' ...
                    'Features:\n' ...
                    '• Interactive simulation\n' ...
                    '• Time constant calculation\n' ...
                    '• Voltage at τ\n' ...
                    '• CSV Export\n' ...
                    '• Save Plot']);

                uialert(app.UIFigure,msg,'About');
        end

function drawCircuit(app)

% Clear circuit area

cla(app.CircuitAxes)


% Set drawing area

axis(app.CircuitAxes,[0 10 0 10])

axis(app.CircuitAxes,'off')

hold(app.CircuitAxes,'on')


%% Battery

plot(app.CircuitAxes,...
    [2 2],...
    [4 6],...
    'LineWidth',3)

text(app.CircuitAxes,...
    1.5,...
    7,...
    '+ 5V')


%% Wires

plot(app.CircuitAxes,...
    [2 5],...
    [6 6],...
    'LineWidth',2)


plot(app.CircuitAxes,...
    [2 5],...
    [4 4],...
    'LineWidth',2)


%% Resistor

plot(app.CircuitAxes,...
    [5 5.5 4.5 6 5 5.5 4.5 6],...
    [6 5.7 5.4 5.1 4.8 4.5 4.2 4],...
    'LineWidth',2)


text(app.CircuitAxes,...
    4.5,...
    7,...
    'R')


%% Capacitor Plates

plot(app.CircuitAxes,...
    [7 7],...
    [5.3 6.7],...
    'LineWidth',3)


plot(app.CircuitAxes,...
    [8 8],...
    [5.3 6.7],...
    'LineWidth',3)


% Wires to capacitor

plot(app.CircuitAxes,...
    [6 7],...
    [6 6],...
    'LineWidth',2)


plot(app.CircuitAxes,...
    [8 9],...
    [6 6],...
    'LineWidth',2)


% Bottom wire

plot(app.CircuitAxes,...
    [9 9 2],...
    [6 4 4],...
    'LineWidth',2)


text(app.CircuitAxes,...
    7,...
    7.2,...
    'C')


title(app.CircuitAxes,...
    'RC Circuit')


hold(app.CircuitAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RC Response Plot ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CircuitAxes = uiaxes(app.UIFigure);
            title(app.CircuitAxes, 'Circuit')
            xlabel(app.CircuitAxes, 'X')
            ylabel(app.CircuitAxes, 'Y')
            zlabel(app.CircuitAxes, 'Z')
            app.ResistanceLabel = uilabel(app.UIFigure);
            app.ResistanceLabel.Text = 'Resistance (Ω)';
            app.ResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapacitanceFEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitanceFEditFieldLabel.Text = 'Capacitance (F)';
            app.CapacitanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageFVEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageFVEditFieldLabel.Text = 'Voltage (FV)';
            app.VoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TauLabel = uilabel(app.UIFigure);
            app.TauLabel.Text = 'Tau';
            app.VoltageTauLabel = uilabel(app.UIFigure);
            app.VoltageTauLabel.Text = 'Voltage @ τ';
            app.FinalVoltageLabel = uilabel(app.UIFigure);
            app.FinalVoltageLabel.Text = 'Final Voltage';
            app.SavePlotButton = uibutton(app.UIFigure, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'RC Circuit Transient Response Simulator ';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RC_Circuit_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end